classdef strainXplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        DisplayTab                  matlab.ui.container.Tab
        GridLayout12                matlab.ui.container.GridLayout
        OrientationSlider           matlab.ui.control.Slider
        OrientationSliderLabel      matlab.ui.control.Label
        UnitvectorCheckBox          matlab.ui.control.CheckBox
        eigVCheckBox                matlab.ui.control.CheckBox
        eigUCheckBox                matlab.ui.control.CheckBox
        CircleCheckBox              matlab.ui.control.CheckBox
        SquareCheckBox              matlab.ui.control.CheckBox
        FTab                        matlab.ui.container.Tab
        GridLayout10                matlab.ui.container.GridLayout
        mathbfFTextArea             matlab.ui.control.TextArea
        mathbfFTextAreaLabel        matlab.ui.control.Label
        RotationEditField           matlab.ui.control.NumericEditField
        RotationEditFieldLabel      matlab.ui.control.Label
        lambda_2EditField           matlab.ui.control.NumericEditField
        lambda_2EditFieldLabel      matlab.ui.control.Label
        lambda_1EditField           matlab.ui.control.NumericEditField
        lambda_1EditFieldLabel      matlab.ui.control.Label
        StretchangleEditField       matlab.ui.control.NumericEditField
        StretchangleEditFieldLabel  matlab.ui.control.Label
        URTab                       matlab.ui.container.Tab
        GridLayout11                matlab.ui.container.GridLayout
        mathbfRTextArea             matlab.ui.control.TextArea
        mathbfRTextAreaLabel        matlab.ui.control.Label
        mathbfUTextArea             matlab.ui.control.TextArea
        mathbfUTextAreaLabel        matlab.ui.control.Label
        RVTab                       matlab.ui.container.Tab
        GridLayout11_2              matlab.ui.container.GridLayout
        mathbfVTextArea             matlab.ui.control.TextArea
        mathbfVTextAreaLabel        matlab.ui.control.Label
        mathbfRTextArea_2           matlab.ui.control.TextArea
        mathbfRTextArea_2Label      matlab.ui.control.Label
        K1K2K3Tab                   matlab.ui.container.Tab
        GridLayout13                matlab.ui.container.GridLayout
        K_3EditField                matlab.ui.control.EditField
        K_3EditFieldLabel           matlab.ui.control.Label
        K_2EditField                matlab.ui.control.EditField
        K_2EditFieldLabel           matlab.ui.control.Label
        K_1EditField                matlab.ui.control.EditField
        K_1EditFieldLabel           matlab.ui.control.Label
        TransformationPanel         matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        DDropDown                   matlab.ui.control.DropDown
        DDropDownLabel              matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        RotationSlider              matlab.ui.control.Slider
        RotationSliderLabel         matlab.ui.control.Label
        lambda_2Slider              matlab.ui.control.Slider
        lambda_2SliderLabel         matlab.ui.control.Label
        lambda_1Slider              matlab.ui.control.Slider
        lambda_1Label               matlab.ui.control.Label
        StretchangleSlider          matlab.ui.control.Slider
        StretchangleLabel           matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        Image4                      matlab.ui.control.Image
        mathrmdvecxmathbfVcdotmathrmdvecXLabel  matlab.ui.control.Label
        GridLayout8                 matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        mathrmdvecxmathbfRcdotmathrmdvecXLabel  matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        Image3                      matlab.ui.control.Image
        mathrmdvecxmathbfRcdotmathrmdvecXLabel_2  matlab.ui.control.Label
        GridLayout6                 matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        mathrmdvecXmathbfUcdotmathrmdvecXLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pts_circle (2,:) double % Description
        pts_square (2,:) double
        h_c0
        h_cf
        h_s0
        h_sf

        h_cU
        h_sU

        h_cR
        h_sR

        h_eigU0
        h_eigU
        h_eigU2

        h_eigV0
        h_eigV
        h_eigV2

        h_n
        h_Un
        h_Rn
        h_Fn


        n (2,1) double
        Q (2,2) double
        R (2,2) double % R is actually the transpose here
        U (2,2) double
        F (2,2) double
        V (2,2) double
        l1 (1,1) double
        l2 (1,1) double
        l3 (1,1) double
        alpha (1,1) double
        theta (1,1) double
        eigU (2,2) double
        eigV (2,2) double
        tformat string % Description
    end
    
    methods (Access = private)
        
        function update_all(app)
            
            if strcmp(app.DDropDown.Value,"Plane Strain")
                app.l3 = 1;
            else
                app.l3 = 1/(app.l1*app.l2);
            end

            app.Q = [cos(app.alpha) -sin(app.alpha);sin(app.alpha) cos(app.alpha)];
            app.R = [cos(app.theta) -sin(app.theta);sin(app.theta) cos(app.theta)];
            app.U = app.Q*diag([app.l1 app.l2])*app.Q';
            app.F = app.R*app.U;
            app.V = app.F*app.R';

            app.eigU = app.Q*diag([app.l1 app.l2]);
            app.eigV = app.R*app.eigU;

            tmp = app.F*app.pts_square;
            set(app.h_sf,'Xdata',tmp(1,:),'YData',tmp(2,:));
            tmp = app.F*app.pts_circle;
            set(app.h_cf,'Xdata',tmp(1,:),'YData',tmp(2,:));
            

            tmp = app.U*app.pts_square;
            set(app.h_sU,'Xdata',tmp(1,:),'YData',tmp(2,:));
            tmp = app.U*app.pts_circle;
            set(app.h_cU,'Xdata',tmp(1,:),'YData',tmp(2,:));
            
            tmp = app.R*app.pts_square;
            set(app.h_sR,'Xdata',tmp(1,:),'YData',tmp(2,:));
            tmp = app.R*app.pts_circle;
            set(app.h_cR,'Xdata',tmp(1,:),'YData',tmp(2,:));

            set(app.h_eigU,'UData',app.eigU(1,:),'VData',app.eigU(2,:));
            set(app.h_eigU0,'UData',app.eigU(1,:),'VData',app.eigU(2,:));
            set(app.h_eigV,'UData',app.eigV(1,:),'VData',app.eigV(2,:));
            set(app.h_eigV0,'UData',app.eigV(1,:),'VData',app.eigV(2,:));
            
            set(app.h_eigV2,'UData',-app.eigV(1,:),'VData',-app.eigV(2,:)); 
            set(app.h_eigU2,'UData',-app.eigU(1,:),'VData',-app.eigU(2,:));

            tmp = app.F;
            tmp(3,3)=app.l3;
            app.mathbfFTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;

            tmp = app.U;
            tmp(3,3)=app.l3;
            app.mathbfUTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            

            tmp = app.R;
            tmp(3,3)=1;
            app.mathbfRTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            app.mathbfRTextArea_2.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;

            tmp = app.V;
            tmp(3,3)=app.l3;
            app.mathbfVTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            
            set(app.h_n,'UData',app.n(1),'VData',app.n(2));
            tmp = app.U*app.n;
            set(app.h_Un,'UData',tmp(1),'VData',tmp(2));
            tmp = app.R*app.n;
            set(app.h_Rn,'UData',tmp(1),'VData',tmp(2));
            tmp = app.F*app.n;
            set(app.h_Fn,'UData',tmp(1),'VData',tmp(2));

            tmp = app.V;
            tmp(3,3) = app.l3;
            Hloc = logm(tmp); 
            K1= trace(Hloc);
            dHloc = Hloc-K1*eye(3)/3;
            K2 = sqrt(trace(dHloc*dHloc));
            K3 = (3*sqrt(6)/(K2^3)) * det(dHloc);

            if abs(K1)<1e-10
                K1=0;
            end
            if abs(K2)<1e-10
                K2=0;
            end
            if abs(K3)<1e-10
                K3=0;
            end
            if abs(K3)>0.99999
                K3 = sign(K3);
            end

            app.K_1EditField.Value=sprintf("%5.3g",K1);
            app.K_2EditField.Value=sprintf("%5.3g",K2);
            app.K_3EditField.Value=sprintf("%5.3g",K3);
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mhs=0.5;
            app.tformat = "%5.3g %5.3g %5.3g\n%5.3g %5.3g %5.3g\n%5.3g %5.3g %5.3g";
            app.l1= 10.^app.lambda_1Slider.Value;
            app.l2= 10.^app.lambda_2Slider.Value;
            app.l3 = 1;
            app.alpha = pi/180*app.StretchangleSlider.Value;
            app.theta = pi/180*app.RotationSlider.Value;
            tmp = pi/180*app.OrientationSlider.Value;

            app.n = [cos(tmp);sin(tmp)];

            app.eigU = eye(2);
            app.eigV = eye(2);
            

            app.Q = [cos(app.alpha) -sin(app.alpha);sin(app.alpha) cos(app.alpha)];
            app.R = [cos(app.theta) -sin(app.theta);sin(app.theta) cos(app.theta)];
            app.U = app.Q*diag([app.l1 app.l2])*app.Q';
            app.F = app.R*app.U;
            app.V = app.F*app.R';


            tmp0 = linspace(0,2*pi,200);
            app.pts_circle =   [cos(tmp0); sin(tmp0)];
            app.pts_square = [-1 1 1 -1 -1;-1 -1 1 1 -1];
            
            app.h_s0 = fill(app.UIAxes,app.pts_square(1,:),app.pts_square(2,:),'g','edgecolor','none','facealpha',0.3);
            app.h_c0 = fill(app.UIAxes,app.pts_circle(1,:),app.pts_circle(2,:),'g','edgecolor','k','facealpha',0);
            app.h_eigU0 = quiver(app.UIAxes,[0 0],[0 0],app.eigU(1,:),app.eigU(2,:),'off','color','r','LineWidth',2,'MaxHeadSize',mhs);
            app.h_eigU2 = quiver(app.UIAxes,[0 0],[0 0],-app.eigU(1,:),-app.eigU(2,:),'off','color','none','LineWidth',2,'MaxHeadSize',mhs);
            axis(app.UIAxes, 'equal');

            tmp = app.F*app.pts_square;
            app.h_sf = fill(app.UIAxes_4,tmp(1,:),tmp(2,:),'g','edgecolor','none','facealpha',0.3);
            tmp = app.F*app.pts_circle;
            app.h_cf = fill(app.UIAxes_4,tmp(1,:),tmp(2,:),'g','edgecolor','k','facealpha',0);
            app.h_eigV0 = quiver(app.UIAxes_4,[0 0],[0 0],app.eigV(1,:),app.eigV(2,:),'off','color','m','LineWidth',2,'MaxHeadSize',mhs);
            axis(app.UIAxes_4, 'equal');

            tmp = app.U*app.pts_square;
            app.h_sU = fill(app.UIAxes_2,tmp(1,:),tmp(2,:),'g','edgecolor','none','facealpha',0.3);
            tmp = app.U*app.pts_circle;
            app.h_cU = fill(app.UIAxes_2,tmp(1,:),tmp(2,:),'g','edgecolor','k','facealpha',0);
            app.h_eigU = quiver(app.UIAxes_2,[0 0],[0 0],app.eigU(1,:),app.eigU(2,:),'off','color','r','LineWidth',2,'MaxHeadSize',mhs);
            axis(app.UIAxes_2, 'equal');

            tmp = app.R*app.pts_square;
            app.h_sR = fill(app.UIAxes_3,tmp(1,:),tmp(2,:),'g','edgecolor','none','facealpha',0.3);
            tmp = app.R*app.pts_circle;
            app.h_cR = fill(app.UIAxes_3,tmp(1,:),tmp(2,:),'g','edgecolor','k','facealpha',0);
            app.h_eigV = quiver(app.UIAxes_3,[0 0],[0 0],app.eigV(1,:),app.eigV(2,:),'off','color','m','LineWidth',2,'MaxHeadSize',mhs);
            app.h_eigV2 = quiver(app.UIAxes_3,[0 0],[0 0],-app.eigV(1,:),-app.eigV(2,:),'off','color','none','LineWidth',2,'MaxHeadSize',mhs);
            
            axis(app.UIAxes_3, 'equal');

            tmp = app.F;
            tmp(3,3)=1;
            app.mathbfFTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;

            tmp = app.U;
            tmp(3,3)=app.l3;
            app.mathbfUTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            

            tmp = app.R;
            tmp(3,3)=1;
            app.mathbfRTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            app.mathbfRTextArea_2.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;

            tmp = app.V;
            tmp(3,3)=app.l3;
            app.mathbfVTextArea.Value = sprintf(app.tformat,tmp(1,1),tmp(1,2),tmp(1,3),tmp(2,1),tmp(2,2),tmp(2,3),tmp(3,1),tmp(3,2),tmp(3,3)) ;
            
            app.h_n = quiver(app.UIAxes,[0],[0],app.n(1),app.n(2),'off','color','b','LineWidth',2,'MaxHeadSize',mhs);
            tmp = app.U*app.n;
            app.h_Un = quiver(app.UIAxes_2,[0],[0],tmp(1),tmp(2),'off','color','b','LineWidth',2,'MaxHeadSize',mhs);
            tmp = app.R'*app.n;
            app.h_Rn = quiver(app.UIAxes_3,[0],[0],tmp(1),tmp(2),'off','color','b','LineWidth',2,'MaxHeadSize',mhs);
            tmp = app.F*app.n;
            app.h_Fn = quiver(app.UIAxes_4,[0],[0],tmp(1),tmp(2),'off','color','b','LineWidth',2,'MaxHeadSize',mhs);
            
            set([app.h_c0 app.h_cR app.h_cU app.h_cf],'visible',app.CircleCheckBox.Value);
            set([app.h_s0 app.h_sR app.h_sU app.h_sf],'visible',app.SquareCheckBox.Value);
            set([app.h_eigU0 app.h_eigU],'visible',app.eigUCheckBox.Value);
            set([app.h_eigV0 app.h_eigV],'visible',app.eigVCheckBox.Value);
            set([app.h_n,app.h_Un,app.h_Rn,app.h_Fn],'visible',app.UnitvectorCheckBox.Value);
            

            % hold(app.UIAxes,'on');
            % hold(app.UIAxes_2,'on');
            % hold(app.UIAxes_3,'on');
            % hold(app.UIAxes_4,'on');
            axis(app.UIAxes,axis(app.UIAxes));
            axis(app.UIAxes_2,axis(app.UIAxes_2));
            axis(app.UIAxes_3,axis(app.UIAxes_3));
            axis(app.UIAxes_4,axis(app.UIAxes_4));


            update_all(app);
        end

        % Value changed function: lambda_1Slider
        function lambda_1SliderValueChanged(app, event)
            app.l1= 10.^app.lambda_1Slider.Value;
            app.lambda_1EditField.Value=app.l1;
            update_all(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RotationSlider.Value=0;
            app.theta=0;
            app.StretchangleSlider.Value=0;
            app.alpha=0;
            app.lambda_1Slider.Value=0;
            app.l1 = 1;
            app.lambda_2Slider.Value=0;
            app.l2 = 1;
            app.RotationEditField.Value = 0;
            app.lambda_1EditField.Value = 1;
            app.lambda_2EditField.Value = 1;
            app.StretchangleEditField.Value=0;
            app.OrientationSlider.Value = 0;
            app.n = [1;0];
            app.DDropDown.Value = app.DDropDown.Items{1};
            update_all(app);
        end

        % Value changing function: RotationSlider
        function RotationSliderValueChanging(app, event)
            app.theta= pi/180*event.Value;
            app.RotationEditField.Value=app.theta*180/pi;
            update_all(app);
        end

        % Value changing function: lambda_2Slider
        function lambda_2SliderValueChanging(app, event)
            app.l2= 10.^event.Value;
            app.lambda_2EditField.Value=app.l2;
            update_all(app);
        end

        % Value changing function: lambda_1Slider
        function lambda_1SliderValueChanging(app, event)
            app.l1 = 10.^event.Value;
            app.lambda_1EditField.Value=app.l1;
            update_all(app)
        end

        % Value changing function: StretchangleSlider
        function StretchangleSliderValueChanging(app, event)
            app.alpha = pi/180*event.Value;
            app.StretchangleEditField.Value=app.alpha*180/pi;
            update_all(app);
        end

        % Value changed function: StretchangleSlider
        function StretchangleSliderValueChanged(app, event)
            app.alpha = pi/180*app.StretchangleSlider.Value;
            app.StretchangleEditField.Value=app.alpha*180/pi;
            update_all(app);
        end

        % Value changed function: lambda_2Slider
        function lambda_2SliderValueChanged(app, event)
            app.l2 = 10.^app.lambda_2Slider.Value;
            app.lambda_2EditField.Value=app.l2;
            update_all(app);
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            app.theta = pi/180*app.RotationSlider.Value;
            app.RotationEditField.Value=app.theta*180/pi;
            update_all(app);
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            app.theta = pi/180*app.RotationEditField.Value;
            app.RotationSlider.Value = app.RotationEditField.Value;
            update_all(app);
        end

        % Value changed function: lambda_2EditField
        function lambda_2EditFieldValueChanged(app, event)
            value = app.lambda_2EditField.Value;
            app.l2 = value;
            app.lambda_2Slider.Value = max(min(log10(value),app.lambda_2Slider.Limits(2)),app.lambda_2Slider.Limits(1));
            update_all(app);
        end

        % Value changed function: lambda_1EditField
        function lambda_1EditFieldValueChanged(app, event)
            value = app.lambda_1EditField.Value;
            app.l1 = value;
            app.lambda_1Slider.Value = max(min(log10(value),app.lambda_1Slider.Limits(2)),app.lambda_1Slider.Limits(1));
            update_all(app);
        end

        % Value changed function: StretchangleEditField
        function StretchangleEditFieldValueChanged(app, event)
            value = app.StretchangleEditField.Value;
            app.alpha = pi/180*value;
            app.StretchangleSlider.Value=value;
            update_all(app);
        end

        % Value changed function: OrientationSlider
        function OrientationSliderValueChanged(app, event)
            value = pi/180*app.OrientationSlider.Value;
            app.n = [cos(value);sin(value)];
            update_all(app);
        end

        % Value changing function: OrientationSlider
        function OrientationSliderValueChanging(app, event)
            changingValue = pi/180*event.Value;
            app.n = [cos(changingValue);sin(changingValue)];
            update_all(app);
        end

        % Value changed function: UnitvectorCheckBox
        function UnitvectorCheckBoxValueChanged(app, event)
            value = app.UnitvectorCheckBox.Value;
            set([app.h_n,app.h_Un,app.h_Rn,app.h_Fn],'visible',value);
        end

        % Value changed function: eigVCheckBox
        function eigVCheckBoxValueChanged(app, event)
            value = app.eigVCheckBox.Value;
            set([app.h_eigV0 app.h_eigV],'visible',value);
        end

        % Value changed function: eigUCheckBox
        function eigUCheckBoxValueChanged(app, event)
            value = app.eigUCheckBox.Value;
            set([app.h_eigU0 app.h_eigU],'visible',value);
        end

        % Value changed function: CircleCheckBox
        function CircleCheckBoxValueChanged(app, event)
            value = app.CircleCheckBox.Value;
            set([app.h_c0 app.h_cR app.h_cU app.h_cf],'visible',value);
        end

        % Value changed function: SquareCheckBox
        function SquareCheckBoxValueChanged(app, event)
            value = app.SquareCheckBox.Value;
            set([app.h_s0 app.h_sR app.h_sU app.h_sf],'visible',value);
        end

        % Value changed function: DDropDown
        function DDropDownValueChanged(app, event)
            update_all(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {526, 526};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TransformationPanel = uipanel(app.GridLayout3);
            app.TransformationPanel.Title = 'Transformation';
            app.GridLayout4 = uigridlayout(app.TransformationPanel);
            app.StretchangleLabel = uilabel(app.GridLayout4);
            app.StretchangleLabel.Text = 'Stretch angle';
            app.StretchangleSlider = uislider(app.GridLayout4);
            app.StretchangleSlider.Limits = [-90 90];
            app.StretchangleSlider.MajorTicks = [-90 -45 0 45 90];
            app.StretchangleSlider.ValueChangedFcn = createCallbackFcn(app, @StretchangleSliderValueChanged, true);
            app.StretchangleSlider.ValueChangingFcn = createCallbackFcn(app, @StretchangleSliderValueChanging, true);
            app.StretchangleSlider.MinorTicks = [];
            app.lambda_1Label = uilabel(app.GridLayout4);
            app.lambda_1Label.Interpreter = 'latex';
            app.lambda_1Label.Text = '$\lambda_1$';
            app.lambda_1Slider = uislider(app.GridLayout4);
            app.lambda_1Slider.Limits = [-0.301029995663981 0.301029995663981];
            app.lambda_1Slider.MajorTicks = [-0.301029995663981 0 0.301029995663981];
            app.lambda_1Slider.MajorTickLabels = {'0.5', '1', '2'};
            app.lambda_1Slider.ValueChangedFcn = createCallbackFcn(app, @lambda_1SliderValueChanged, true);
            app.lambda_1Slider.ValueChangingFcn = createCallbackFcn(app, @lambda_1SliderValueChanging, true);
            app.lambda_1Slider.MinorTicks = [];
            app.lambda_2SliderLabel = uilabel(app.GridLayout4);
            app.lambda_2SliderLabel.Interpreter = 'latex';
            app.lambda_2SliderLabel.Text = '$\lambda_2$';
            app.lambda_2Slider = uislider(app.GridLayout4);
            app.lambda_2Slider.Limits = [-0.301029995663981 0.301029995663981];
            app.lambda_2Slider.MajorTicks = [-0.301029995663981 0 0.301029995663981];
            app.lambda_2Slider.MajorTickLabels = {'0.5', '1', '2'};
            app.lambda_2Slider.ValueChangedFcn = createCallbackFcn(app, @lambda_2SliderValueChanged, true);
            app.lambda_2Slider.ValueChangingFcn = createCallbackFcn(app, @lambda_2SliderValueChanging, true);
            app.lambda_2Slider.MinorTicks = [];
            app.RotationSliderLabel = uilabel(app.GridLayout4);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.GridLayout4);
            app.RotationSlider.Limits = [-180 180];
            app.RotationSlider.MajorTicks = [-180 -90 0 90 180];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.RotationSlider.ValueChangingFcn = createCallbackFcn(app, @RotationSliderValueChanging, true);
            app.RotationSlider.MinorTicks = [];
            app.ResetButton = uibutton(app.GridLayout4, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DDropDownLabel = uilabel(app.GridLayout4);
            app.DDropDownLabel.Text = '3D';
            app.DDropDown = uidropdown(app.GridLayout4);
            app.DDropDown.Items = {'Plane Strain', 'Isochoric'};
            app.DDropDown.ValueChangedFcn = createCallbackFcn(app, @DDropDownValueChanged, true);
            app.DDropDown.Value = 'Plane Strain';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.GridLayout12 = uigridlayout(app.DisplayTab);
            app.SquareCheckBox = uicheckbox(app.GridLayout12);
            app.SquareCheckBox.ValueChangedFcn = createCallbackFcn(app, @SquareCheckBoxValueChanged, true);
            app.SquareCheckBox.Text = 'Square';
            app.SquareCheckBox.Value = true;
            app.CircleCheckBox = uicheckbox(app.GridLayout12);
            app.CircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircleCheckBoxValueChanged, true);
            app.CircleCheckBox.Text = 'Circle';
            app.CircleCheckBox.Value = true;
            app.eigUCheckBox = uicheckbox(app.GridLayout12);
            app.eigUCheckBox.ValueChangedFcn = createCallbackFcn(app, @eigUCheckBoxValueChanged, true);
            app.eigUCheckBox.Text = 'eig-U';
            app.eigVCheckBox = uicheckbox(app.GridLayout12);
            app.eigVCheckBox.ValueChangedFcn = createCallbackFcn(app, @eigVCheckBoxValueChanged, true);
            app.eigVCheckBox.Text = 'eig-V';
            app.UnitvectorCheckBox = uicheckbox(app.GridLayout12);
            app.UnitvectorCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnitvectorCheckBoxValueChanged, true);
            app.UnitvectorCheckBox.Text = 'Unit vector';
            app.UnitvectorCheckBox.Value = true;
            app.OrientationSliderLabel = uilabel(app.GridLayout12);
            app.OrientationSliderLabel.Text = 'Orientation';
            app.OrientationSlider = uislider(app.GridLayout12);
            app.OrientationSlider.Limits = [-180 180];
            app.OrientationSlider.MajorTicks = [-180 -90 0 90 180];
            app.OrientationSlider.ValueChangedFcn = createCallbackFcn(app, @OrientationSliderValueChanged, true);
            app.OrientationSlider.ValueChangingFcn = createCallbackFcn(app, @OrientationSliderValueChanging, true);
            app.OrientationSlider.MinorTicks = [];
            app.FTab = uitab(app.TabGroup);
            app.FTab.Title = 'F';
            app.GridLayout10 = uigridlayout(app.FTab);
            app.StretchangleEditFieldLabel = uilabel(app.GridLayout10);
            app.StretchangleEditFieldLabel.Text = 'Stretch angle';
            app.StretchangleEditField = uieditfield(app.GridLayout10, 'numeric');
            app.StretchangleEditField.Limits = [-90 90];
            app.StretchangleEditField.ValueChangedFcn = createCallbackFcn(app, @StretchangleEditFieldValueChanged, true);
            app.lambda_1EditFieldLabel = uilabel(app.GridLayout10);
            app.lambda_1EditFieldLabel.Interpreter = 'latex';
            app.lambda_1EditFieldLabel.Text = '$\lambda_1$';
            app.lambda_1EditField = uieditfield(app.GridLayout10, 'numeric');
            app.lambda_1EditField.Limits = [0.5 2];
            app.lambda_1EditField.ValueChangedFcn = createCallbackFcn(app, @lambda_1EditFieldValueChanged, true);
            app.lambda_1EditField.Value = 1;
            app.lambda_2EditFieldLabel = uilabel(app.GridLayout10);
            app.lambda_2EditFieldLabel.Interpreter = 'latex';
            app.lambda_2EditFieldLabel.Text = '$\lambda_2$';
            app.lambda_2EditField = uieditfield(app.GridLayout10, 'numeric');
            app.lambda_2EditField.Limits = [0.5 2];
            app.lambda_2EditField.ValueChangedFcn = createCallbackFcn(app, @lambda_2EditFieldValueChanged, true);
            app.lambda_2EditField.Value = 1;
            app.RotationEditFieldLabel = uilabel(app.GridLayout10);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.GridLayout10, 'numeric');
            app.RotationEditField.Limits = [-180 180];
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            app.mathbfFTextAreaLabel = uilabel(app.GridLayout10);
            app.mathbfFTextAreaLabel.Interpreter = 'latex';
            app.mathbfFTextAreaLabel.Text = '$\mathbf{F}$';
            app.mathbfFTextArea = uitextarea(app.GridLayout10);
            app.mathbfFTextArea.Editable = 'off';
            app.mathbfFTextArea.Value = {'[1.3.   4.56'; ' 45.5. 12.3]'};
            app.URTab = uitab(app.TabGroup);
            app.URTab.Title = 'U,R';
            app.GridLayout11 = uigridlayout(app.URTab);
            app.mathbfUTextAreaLabel = uilabel(app.GridLayout11);
            app.mathbfUTextAreaLabel.Interpreter = 'latex';
            app.mathbfUTextAreaLabel.Text = '$\mathbf{U}$';
            app.mathbfUTextArea = uitextarea(app.GridLayout11);
            app.mathbfUTextArea.Editable = 'off';
            app.mathbfRTextAreaLabel = uilabel(app.GridLayout11);
            app.mathbfRTextAreaLabel.Interpreter = 'latex';
            app.mathbfRTextAreaLabel.Text = '$\mathbf{R}$';
            app.mathbfRTextArea = uitextarea(app.GridLayout11);
            app.mathbfRTextArea.Editable = 'off';
            app.RVTab = uitab(app.TabGroup);
            app.RVTab.Title = 'R,V';
            app.GridLayout11_2 = uigridlayout(app.RVTab);
            app.mathbfRTextArea_2Label = uilabel(app.GridLayout11_2);
            app.mathbfRTextArea_2Label.Interpreter = 'latex';
            app.mathbfRTextArea_2Label.Text = '$\mathbf{R}$';
            app.mathbfRTextArea_2 = uitextarea(app.GridLayout11_2);
            app.mathbfRTextArea_2.Editable = 'off';
            app.mathbfVTextAreaLabel = uilabel(app.GridLayout11_2);
            app.mathbfVTextAreaLabel.Interpreter = 'latex';
            app.mathbfVTextAreaLabel.Text = '$\mathbf{V}$';
            app.mathbfVTextArea = uitextarea(app.GridLayout11_2);
            app.mathbfVTextArea.Editable = 'off';
            app.K1K2K3Tab = uitab(app.TabGroup);
            app.K1K2K3Tab.Title = 'K1,K2,K3';
            app.GridLayout13 = uigridlayout(app.K1K2K3Tab);
            app.K_1EditFieldLabel = uilabel(app.GridLayout13);
            app.K_1EditFieldLabel.Interpreter = 'latex';
            app.K_1EditFieldLabel.Text = '$K_1$';
            app.K_1EditField = uieditfield(app.GridLayout13, 'text');
            app.K_1EditField.Editable = 'off';
            app.K_2EditFieldLabel = uilabel(app.GridLayout13);
            app.K_2EditFieldLabel.Interpreter = 'latex';
            app.K_2EditFieldLabel.Text = '$K_2$';
            app.K_2EditField = uieditfield(app.GridLayout13, 'text');
            app.K_2EditField.Editable = 'off';
            app.K_3EditFieldLabel = uilabel(app.GridLayout13);
            app.K_3EditFieldLabel.Interpreter = 'latex';
            app.K_3EditFieldLabel.Text = '$K_3$';
            app.K_3EditField = uieditfield(app.GridLayout13, 'text');
            app.K_3EditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.UIAxes_4 = uiaxes(app.GridLayout5);
            title(app.UIAxes_4, 'Full transformation')
            app.UIAxes_4.XLim = [-3.1 3.1];
            app.UIAxes_4.YLim = [-3.1 3.1];
            app.UIAxes_4.XAxisLocation = 'origin';
            app.UIAxes_4.XTick = [-3 -2 -1 1 2 3];
            app.UIAxes_4.XTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes_4.YAxisLocation = 'origin';
            app.UIAxes_4.YTick = [-3 -2 -1 1 2 3];
            app.UIAxes_4.YTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes_3 = uiaxes(app.GridLayout5);
            title(app.UIAxes_3, 'Rotation only')
            app.UIAxes_3.XLim = [-3.1 3.1];
            app.UIAxes_3.YLim = [-3.1 3.1];
            app.UIAxes_3.XAxisLocation = 'origin';
            app.UIAxes_3.XTick = [-3 -2 -1 1 2 3];
            app.UIAxes_3.XTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes_3.YAxisLocation = 'origin';
            app.UIAxes_3.YTick = [-3 -2 -1 1 2 3];
            app.UIAxes_3.YTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes_2, 'Stretch only')
            app.UIAxes_2.XLim = [-3.1 3.1];
            app.UIAxes_2.YLim = [-3.1 3.1];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.XTick = [-3 -2 -1 1 2 3];
            app.UIAxes_2.XTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes_2.YAxisLocation = 'origin';
            app.UIAxes_2.YTick = [-3 -2 -1 1 2 3];
            app.UIAxes_2.YTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'Reference')
            app.UIAxes.XLim = [-3.1 3.1];
            app.UIAxes.YLim = [-3.1 3.1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-3 -2 -1 1 2 3];
            app.UIAxes.XTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-3 -2 -1 1 2 3];
            app.UIAxes.YTickLabel = {'-3'; '-2'; '-1'; '1'; '2'; '3'};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.mathrmdvecXmathbfUcdotmathrmdvecXLabel = uilabel(app.GridLayout6);
            app.mathrmdvecXmathbfUcdotmathrmdvecXLabel.Interpreter = 'latex';
            app.mathrmdvecXmathbfUcdotmathrmdvecXLabel.Text = '$\mathrm{d}\vec{X}^{\,*} = \mathbf{U} \cdot \mathrm{d}\vec{X}$';
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fleche.png');
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel_2 = uilabel(app.GridLayout7);
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel_2.Interpreter = 'latex';
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel_2.Text = '$\mathrm{d}\vec{x}^{\,**} = \mathbf{R} \cdot \mathrm{d}\vec{X}$';
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'flecheD.png');
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel = uilabel(app.GridLayout8);
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel.Interpreter = 'latex';
            app.mathrmdvecxmathbfRcdotmathrmdvecXLabel.Text = '$\mathrm{d}\vec{x} = \mathbf{R} \cdot \mathrm{d}\vec{X}^{\,*}$';
            app.Image2 = uiimage(app.GridLayout8);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'flecheD.png');
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.mathrmdvecxmathbfVcdotmathrmdvecXLabel = uilabel(app.GridLayout9);
            app.mathrmdvecxmathbfVcdotmathrmdvecXLabel.Interpreter = 'latex';
            app.mathrmdvecxmathbfVcdotmathrmdvecXLabel.Text = '$\mathrm{d}\vec{x} = \mathbf{V} \cdot \mathrm{d}\vec{X}^{\,**}$';
            app.Image4 = uiimage(app.GridLayout9);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'fleche.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = strainXplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end